classdef mri_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RawTab                          matlab.ui.container.Tab
        ax_raw_dicom                    matlab.ui.control.UIAxes
        FilteredTab                     matlab.ui.container.Tab
        ax_filtered_dicom               matlab.ui.control.UIAxes
        MarkventriclesTab               matlab.ui.container.Tab
        ax_mv_circle                    matlab.ui.control.UIAxes
        ax_mv_mask                      matlab.ui.control.UIAxes
        ax_mv_std                       matlab.ui.control.UIAxes
        ax_mv_bin                       matlab.ui.control.UIAxes
        ax_mv_bin_thick                 matlab.ui.control.UIAxes
        ax_mv_filled                    matlab.ui.control.UIAxes
        ax_mv_contouring                matlab.ui.control.UIAxes
        ax_mv_L                         matlab.ui.control.UIAxes
        ax_mv_thick_masked              matlab.ui.control.UIAxes
        ax_mv_ventricles                matlab.ui.control.UIAxes
        ProfilingTab                    matlab.ui.container.Tab
        ax_pr_sep_epi_im                matlab.ui.control.UIAxes
        ax_pr_opp_epi_im                matlab.ui.control.UIAxes
        ax_pr_endo_im                   matlab.ui.control.UIAxes
        ax_pr_sep_epi_profile           matlab.ui.control.UIAxes
        ax_pr_opp_epi_profile           matlab.ui.control.UIAxes
        ax_pr_endo_profile              matlab.ui.control.UIAxes
        ThicknessTab                    matlab.ui.container.Tab
        ax_th_ellipses                  matlab.ui.control.UIAxes
        ax_th_thick                     matlab.ui.control.UIAxes
        ResultTab                       matlab.ui.container.Tab
        ax_result_im                    matlab.ui.control.UIAxes
        DICOMPanel                      matlab.ui.container.Panel
        DICOMfileEditFieldLabel         matlab.ui.control.Label
        DICOMfileEditField              matlab.ui.control.EditField
        SelectButton                    matlab.ui.control.Button
        FrameSpinnerLabel               matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        LoadButton                      matlab.ui.control.Button
        SettingsEditFieldLabel          matlab.ui.control.Label
        SettingsEditField               matlab.ui.control.EditField
        LoadSettingsButton              matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        VentricledetectionTab           matlab.ui.container.Tab
        GaussfilterSDEditFieldLabel     matlab.ui.control.Label
        GaussfilterSDEditField          matlab.ui.control.NumericEditField
        CircleexpansionEditFieldLabel   matlab.ui.control.Label
        CircleexpansionEditField        matlab.ui.control.NumericEditField
        ContractionbiasEditFieldLabel   matlab.ui.control.Label
        ContractionbiasEditField        matlab.ui.control.NumericEditField
        SmoothnessfactorEditFieldLabel  matlab.ui.control.Label
        SmoothnessfactorEditField       matlab.ui.control.NumericEditField
        ContouriterationsEditFieldLabel  matlab.ui.control.Label
        ContouriterationsEditField      matlab.ui.control.NumericEditField
        StandarddeviationfilterEditFieldLabel  matlab.ui.control.Label
        StandarddeviationfilterEditField  matlab.ui.control.NumericEditField
        EdgethickeningEditFieldLabel    matlab.ui.control.Label
        EdgethickeningEditField         matlab.ui.control.NumericEditField
        SeedcontrastEditFieldLabel      matlab.ui.control.Label
        SeedcontrastEditField           matlab.ui.control.NumericEditField
        ProfilingTab_2                  matlab.ui.container.Tab
        SeptumstartangleEditFieldLabel  matlab.ui.control.Label
        SeptumstartangleEditField       matlab.ui.control.NumericEditField
        SeptumstopangleEditFieldLabel   matlab.ui.control.Label
        SeptumstopangleEditField        matlab.ui.control.NumericEditField
        SeptumpointsEditFieldLabel      matlab.ui.control.Label
        SeptumpointsEditField           matlab.ui.control.NumericEditField
        PeakprominenceEditFieldLabel    matlab.ui.control.Label
        PeakprominenceEditField         matlab.ui.control.NumericEditField
        PixelinterpolationEditFieldLabel  matlab.ui.control.Label
        PixelinterpolationEditField     matlab.ui.control.NumericEditField
        OppositestartangleEditFieldLabel  matlab.ui.control.Label
        OppositestartangleEditField     matlab.ui.control.NumericEditField
        OppositestopangleEditFieldLabel  matlab.ui.control.Label
        OppositestopangleEditField      matlab.ui.control.NumericEditField
        OppositepointsEditFieldLabel    matlab.ui.control.Label
        OppositepointsEditField         matlab.ui.control.NumericEditField
        EndocardiumstartangleEditFieldLabel  matlab.ui.control.Label
        EndocardiumstartangleEditField  matlab.ui.control.NumericEditField
        EndocardiumstopangleEditFieldLabel  matlab.ui.control.Label
        EndocardiumstopangleEditField   matlab.ui.control.NumericEditField
        EndocardiumpointsEditFieldLabel  matlab.ui.control.Label
        EndocardiumpointsEditField      matlab.ui.control.NumericEditField
        OppositethresholdEditFieldLabel  matlab.ui.control.Label
        OppositethresholdEditField      matlab.ui.control.NumericEditField
        EndocardialthresholdEditFieldLabel  matlab.ui.control.Label
        EndocardialthresholdEditField   matlab.ui.control.NumericEditField
        SeptumthresholdEditFieldLabel   matlab.ui.control.Label
        SeptumthresholdEditField        matlab.ui.control.NumericEditField
        ThicknessTab_2                  matlab.ui.container.Tab
        NoofthicknesscalculationsEditFieldLabel  matlab.ui.control.Label
        NoofthicknesscalculationsEditField  matlab.ui.control.NumericEditField
        SaveTab                         matlab.ui.container.Tab
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        dicom_data                  % DICOM data
        dicom_raw_frame             % raw DICOM frame
        dicom_filtered_frame        % Frame after Gaussian filtering
        im_lv_mask                  % mask for LV
        im_rv_mask                  % mask for RV
        lv_props                    % lv props
        rv_props                    % rv_props
    end
    
    methods (Access = private)
        
        function results = mark_ventricles(app)
            % Find circles
            [centers, radii] = imfindcircles(app.dicom_filtered_frame, ...
                                [10, 100], 'ObjectPolarity',"bright");
            
            % Set masks for the circle and enlarged circle
            im_circle = zeros(size(app.dicom_filtered_frame),'logical');
            im_mask = zeros(size(app.dicom_filtered_frame));
            for r = 1 : size(im_circle, 2)
                for c = 1 : size(im_circle, 1)
                    h = hypot(r - centers(1,2), c-centers(1,1));
                    if (h < radii(1))
                        im_circle(r,c) = 1;
                    end
                    if (h < (app.CircleexpansionEditField.Value * radii(1)))
                        im_mask(r,c) = 1;
                    end
                end
            end
            
            % Find the boundary of the circle
            circle_boundary = bwboundaries(im_circle);
            circle_boundary = circle_boundary{1};
            cla(app.ax_mv_circle);
            hold(app.ax_mv_circle, 'on');
            imagesc(app.ax_mv_circle, app.dicom_filtered_frame);
            plot(app.ax_mv_circle, ...
                circle_boundary(:,2), circle_boundary(:,1), ...
                'b-', 'LineWidth',2);
            app.tidy_axes(app.ax_mv_circle, 'Identified circle');
            
            % Draw the mask using transparency
            im_g = zeros([size(im_circle) 3]);
            im_g(:,:,2)=1;
            cla(app.ax_mv_mask);
            hold (app.ax_mv_mask, "on");
            imagesc(app.ax_mv_mask, app.dicom_filtered_frame);
            h = image(app.ax_mv_mask, im_g);
            set(h,'AlphaData', 0.2*im_mask);
            app.tidy_axes(app.ax_mv_mask, 'Ventricle mask');
            
            % Find the edges
            im_std = imadjust(stdfilt(app.dicom_filtered_frame, ...
                                true(app.StandarddeviationfilterEditField.Value)));
            cla(app.ax_mv_std);
            imagesc(app.ax_mv_std, im_std);
            app.tidy_axes(app.ax_mv_std, 'Standard deviation');

            % Binarize
            im_bin = imbinarize(im_std, 'adaptive');
            cla(app.ax_mv_bin);
            imagesc(app.ax_mv_bin, im_bin);
            app.tidy_axes(app.ax_mv_bin, 'Binarized');
            
            % Thicken
            im_thick = imclose(im_bin, strel('disk', ...
                                app.EdgethickeningEditField.Value));
            cla(app.ax_mv_bin_thick);
            imagesc(app.ax_mv_bin_thick, im_thick);
            app.tidy_axes(app.ax_mv_bin_thick, 'Thickened');
            
             % Mask th thickened image
            im_thick_masked = im_thick;
            im_thick_masked(~im_mask)=0;
            cla(app.ax_mv_thick_masked);
            imagesc(app.ax_mv_thick_masked, im_thick_masked);
            app.tidy_axes(app.ax_mv_thick_masked, 'Thick masked');

            % Flood fill
            im_filled = imcomplement(imfill(im_thick_masked,[1,1]));
            im_filled = bwareafilt(im_filled, 2);
            im_filled = imfill(im_filled, 'holes');
            cla(app.ax_mv_filled);
            imagesc(app.ax_mv_filled, im_filled);
            app.tidy_axes(app.ax_mv_filled, 'Ventricles');

            % Get Labels
            im_L = bwlabel(im_filled);
            cla(app.ax_mv_L);
            imagesc(app.ax_mv_L, im_L);
            app.tidy_axes(app.ax_mv_L, 'Labels');
            
            % Set up for contouring
            im_contouring = imcomplement(app.dicom_filtered_frame);
            im_contouring = imadjust(im_contouring, ...
                [0 app.SeedcontrastEditField.Value], [0 1]);
            cla(app.ax_mv_contouring);
            imagesc(app.ax_mv_contouring, im_contouring)
            app.tidy_axes(app.ax_mv_contouring, 'Contouring')
            
            % ActiveContour the ventricles
            % Start with RV
            im_rv = zeros(size(app.dicom_raw_frame));
            im_rv(im_L==2)=1;
            im_rv = activecontour(im_contouring, im_rv, ...
                app.ContouriterationsEditField.Value, ...
                'Chan-Vese', ...
                "SmoothFactor", app.SmoothnessfactorEditField.Value, ...
                "ContractionBias", app.ContractionbiasEditField.Value);

            
            % Now do LV
            im_lv = zeros(size(app.dicom_raw_frame));
            im_lv(im_L==1)=1;
            im_lv = activecontour(im_contouring, im_lv, ...
                app.ContouriterationsEditField.Value, ...
                'Chan-Vese', ...
                "SmoothFactor", app.SmoothnessfactorEditField.Value, ...
                "ContractionBias", app.ContractionbiasEditField.Value);
            lv_ecc = regionprops(im_lv, {'Eccentricity'});
            rv_ecc = regionprops(im_rv, {'Eccentricity'});
            if lv_ecc.Eccentricity > rv_ecc.Eccentricity
                temp = im_lv;
                im_lv = im_rv;
                im_rv = temp;
            end
            
            % Find the LV centroid
            lv_rp = regionprops(im_lv, {'Centroid'});
            app.lv_props.lv_centroid = round(lv_rp(1).Centroid);
            
            % Find the RV centroid
            rv_rp = regionprops(im_rv, {'Centroid'});
            app.rv_props.rv_centroid = round(rv_rp(1).Centroid);   
            
            % Rotate dicom filtered frame (what is used for profiling)
            % law of cosines to fine theta, may need to switch theta sign
            % depending on if rv centroid is to right of lv centroid
            app.lv_props.lv_centroid(1)
            app.lv_props.lv_centroid(2)
            a = 192-app.lv_props.lv_centroid(2);
            b = hypot((app.lv_props.lv_centroid(1)-app.rv_props.rv_centroid(1)),(app.lv_props.lv_centroid(2)-app.rv_props.rv_centroid(2)));
            c = hypot((app.lv_props.lv_centroid(1)-app.rv_props.rv_centroid(1)),(192-app.rv_props.rv_centroid(2)));
            theta = acos(-(c*c -(a*a+b*b))/(2*a*b)); % in radians
            theta = theta*180/pi;
            im_temp = rotateAround(app.dicom_filtered_frame, ...
                        app.lv_props.lv_centroid(2), ...
                        app.lv_props.lv_centroid(1), ...
                        180+theta,'bicubic');
                    
            app.dicom_filtered_frame = im_temp;
           
            % Rotate rv and lv masks
            temp_im_rv = rotateAround(im_rv, ...
                app.lv_props.lv_centroid(2), ...
                app.lv_props.lv_centroid(1), ...
                180+theta);
            temp_im_lv = rotateAround(im_lv, ...
                app.lv_props.lv_centroid(2), ...
                app.lv_props.lv_centroid(1), ...
                180+theta);
            
            im_rv = temp_im_rv;
            im_lv = temp_im_lv;
            % Hold RV mask
            app.rv_props.im_mask = im_rv;
            
            % Hold LV mask
            app.lv_props.im_mask = im_lv;
            % Find the RV centroid
            rv_rp = regionprops(im_rv, {'Centroid'});
            app.rv_props.rv_centroid = round(rv_rp(1).Centroid); 
            
            % Display
            im_back = zeros([size(im_contouring) 3]);
            im_back(:,:,1) = im_rv;
            im_back(:,:,2) = im_lv;
            hold(app.ax_mv_ventricles, 'on')
            imagesc(app.ax_mv_ventricles, app.dicom_filtered_frame);
            h = image(app.ax_mv_ventricles, im_back);
            set(h,'AlphaData',0.2*(im_lv + im_rv));
            plot(app.ax_mv_ventricles, app.lv_props.lv_centroid(1), ...
                app.lv_props.lv_centroid(2), 'yo');
            plot(app.ax_mv_ventricles, app.rv_props.rv_centroid(1), ...
                app.rv_props.rv_centroid(2), 'yo');
            app.tidy_axes(app.ax_mv_ventricles, 'Ventricles');
        end
        
        function results = tidy_axes(app, axis_handle, title_string)
            colormap(axis_handle, "gray")
            xlim(axis_handle, [1 size(app.dicom_raw_frame, 2)])
            ylim(axis_handle, [1 size(app.dicom_raw_frame, 1)])
            set(axis_handle, 'YDir', 'reverse')
            if (~isempty(title_string))
                title(axis_handle, title_string)
            end
        end
        
        function results = profile_analysis(app, rot_angle, region_string)
            % Returns profile analysis

            % Rotate the image
            im_rot = rotateAround(app.dicom_filtered_frame, ...
                        app.lv_props.lv_centroid(2), ...
                        app.lv_props.lv_centroid(1), ...
                        rot_angle);
            
            % Pull of profile
            r = 1 : app.lv_props.lv_centroid(2);
            z = im_rot(r, app.lv_props.lv_centroid(1));
            
            % Interpolate to get sub-pixel resolution
            r_i = 1 : app.PixelinterpolationEditField.Value : numel(r);
            z_i = interp1(r, z, r_i, 'cubic');
            z_i(end) = 0;
            
            % Find peaks and troughs
            [~, p_loc_i] = findpeaks(z_i, ...
                'MinPeakProminence', app.PeakprominenceEditField.Value * ...
                    max(z_i));
            [~, t_loc_i] = findpeaks(-z_i, ...
                'MinPeakProminence', app.PeakprominenceEditField.Value * ...
                    max(z_i));
            
            
            % Display
            switch (region_string)
                case 'Septum Epi'
                    ax_im = app.ax_pr_sep_epi_im;
                    ax_z = app.ax_pr_sep_epi_profile;
                    
                    if (isempty(t_loc_i))
                        min_z = 0;
                    else
                        min_z = z_i(t_loc_i(end));
                    end
                    %thresh = mean([z_i(p_loc_i(end)) min_z]);
                    thresh = app.SeptumthresholdEditField.Value * z_i(p_loc_i(end));
                    ind_i = find((z_i > thresh) & (r_i < r_i(t_loc_i(end))), ...
                                1,'last');
                    if (isempty(ind_i))
                        ind_i = p_loc_i(end-1)
                    end
                
                case 'Opposite Epi'
                    ax_im = app.ax_pr_opp_epi_im;
                    ax_z = app.ax_pr_opp_epi_profile;

                    thresh = app.OppositethresholdEditField.Value * ...
                                z_i(p_loc_i(end));
                    ind_i = find((z_i < thresh) & (r_i < r_i(p_loc_i(end))), ...
                                1,'last');
                    
                case 'Endocardium'
                    ax_im = app.ax_pr_endo_im;
                    ax_z = app.ax_pr_endo_profile;
                    
                    if (isempty(t_loc_i))
                        low_z = 0;
                    else
                        low_z = z_i(t_loc_i(end));
                    end
                    thresh = low_z + ...
                        app.EndocardialthresholdEditField.Value * ...
                                (z_i(p_loc_i(end)) - low_z);
                    ind_i = find((z_i < thresh) & (r_i < r_i(p_loc_i(end))), ...
                                1,'last');
            end

            % Store results
            results.r = r_i(numel(z_i) - ind_i);
            results.x = app.lv_props.lv_centroid(1) + ...
                        results.r * sind(rot_angle);
            results.y = app.lv_props.lv_centroid(2) - ...
                        results.r * cosd(rot_angle);
            
            % Display
            cla(ax_im)
            imagesc(ax_im, im_rot);
            hold(ax_im, 'on');
            plot(ax_im, app.lv_props.lv_centroid(1), ...
                app.lv_props.lv_centroid(2),'y+');
            plot(ax_im, app.lv_props.lv_centroid(1) * [1 1], ...
                [1 app.lv_props.lv_centroid(2)], 'r-');
            plot(ax_im, app.lv_props.lv_centroid(1), ...
                r_i(ind_i),'yo');
            app.tidy_axes(ax_im, region_string);
            
            cla(ax_z)
            hold(ax_z, 'on');
            plot(ax_z, r, z, 'b-');
            plot(ax_z, r_i, z_i, 'm-');
            plot(ax_z, r_i(p_loc_i), z_i(p_loc_i), 'ro');
            plot(ax_z, r_i(t_loc_i), z_i(t_loc_i), 'gd');
            plot(ax_z, r([1 end]), thresh * [1 1], 'c-');
            plot(ax_z, r_i(ind_i), z_i(ind_i), 'ms');
        end
        
        function results = deduce_ellipses(app)
            % Function finds ellipses for the LV endo and epicardium
            
            % Start with the septum epi
            app.lv_props.septum_epi.angles = linspace( ...
                app.SeptumstartangleEditField.Value, ...
                app.SeptumstopangleEditField.Value, ...
                app.SeptumpointsEditField.Value);
            
            for i  = 1 : numel(app.lv_props.septum_epi.angles)
                out = app.profile_analysis( ...
                        app.lv_props.septum_epi.angles(i), ...
                        'Septum Epi');
                app.lv_props.septum_epi.r(i) = out.r;
                app.lv_props.septum_epi.x(i) = out.x;
                app.lv_props.septum_epi.y(i) = out.y;
            end
            
            % Now do the opposite epi
            ang_lims = check_angles( ...
                        app.OppositestartangleEditField.Value, ...
                        app.OppositestopangleEditField.Value);
            app.lv_props.opp_epi.angles = linspace( ...
                ang_lims(1), ang_lims(2), ...
                app.OppositepointsEditField.Value);
            
            for i  = 1 : numel(app.lv_props.opp_epi.angles)
                out = app.profile_analysis( ...
                        app.lv_props.opp_epi.angles(i), ...
                        'Opposite Epi');
                app.lv_props.opp_epi.r(i) = out.r;
                app.lv_props.opp_epi.x(i) = out.x;
                app.lv_props.opp_epi.y(i) = out.y;
            end
            
            % Now do the endocardium
            app.lv_props.endo.angles = linspace( ...
                app.EndocardiumstartangleEditField.Value, ...
                app.EndocardiumstopangleEditField.Value, ...
                app.EndocardiumpointsEditField.Value);
            
            for i  = 1 : numel(app.lv_props.endo.angles)
                out = app.profile_analysis( ...
                    app.lv_props.endo.angles(i), ...
                    'Endocardium');
                app.lv_props.endo.r(i) = out.r;
                app.lv_props.endo.x(i) = out.x;
                app.lv_props.endo.y(i) = out.y;
            end
            
            % Deduce epi ellipse
            epi_x = [app.lv_props.septum_epi.x app.lv_props.opp_epi.x];
            epi_y = [app.lv_props.septum_epi.y app.lv_props.opp_epi.y];
            app.lv_props.epi_x = epi_x;
            app.lv_props.epi_y = epi_y;
            app.lv_props.epi_ellipse = fit_ellipse(epi_x, epi_y);
            %width = max(epi_x)-min(epi_x);
            %polynomialOrder = 2;
            %smoothX = sgolayfilt(epix, polynomialOrder, width);
            %smoothY = sgolayfilt(y, polynomialOrder, width);
            
            
            % Deduce endo ellipse
            app.lv_props.endo_ellipse = fit_ellipse( ...
                app.lv_props.endo.x, app.lv_props.endo.y);
            
                % Nested function
                function ca = check_angles(a, b)
                    % Check angle limits, making sure they are increasing
                    if ((a>0) & (b<0))
                        b = 360 + b;
                    end
                    ca = [a b];
                end
        end
        
        function results = calculate_thickness(app)
            % Calculates wall thickness by finding the normals to the
            % endo ellipse and then finding the intersection with the
            % epi ellipse
            
            ellipse_angles = linspace(0,360,1000);
            %wall_angles = linspace(0, 360, ...
                %app.NoofthicknesscalculationsEditField.Value);
            % Start thickness measurements at start of septum
            start_angle = app.SeptumstartangleEditField.Value;
            wall_angles = linspace(start_angle,start_angle+360,app.NoofthicknesscalculationsEditField.Value);
            wall_angles
            % Calculate a dense endo surface
            endo_centered = [app.lv_props.endo_ellipse.a * cosd(ellipse_angles) ; ...
                    app.lv_props.endo_ellipse.b * sind(ellipse_angles)];
            endo_phi = app.lv_props.endo_ellipse.phi;
            R_endo = [cos(endo_phi) sin(endo_phi) ; ...
                            -sin(endo_phi) cos(endo_phi)];
            endo_offset = R_endo * endo_centered + ...
                                [app.lv_props.endo_ellipse.X0_in ; ...
                                        app.lv_props.endo_ellipse.Y0_in];
            
            % Calculate a dense epi surface
            epi_centered = [app.lv_props.epi_ellipse.a * cosd(ellipse_angles) ; ...
                    app.lv_props.epi_ellipse.b * sind(ellipse_angles)];
            epi_phi = app.lv_props.epi_ellipse.phi;
            R_epi = [cos(epi_phi) sin(epi_phi) ; ...
                            -sin(epi_phi) cos(epi_phi)];
            epi_offset = R_epi * epi_centered + ...
                                [app.lv_props.epi_ellipse.X0_in ; ...
                                        app.lv_props.epi_ellipse.Y0_in];
            
            % Display
            cla(app.ax_th_ellipses);
            hold(app.ax_th_ellipses, 'on');
            imagesc(app.ax_th_ellipses, app.dicom_filtered_frame);
            plot(app.ax_th_ellipses, endo_offset(1,:), ...
                endo_offset(2,:), 'y-');
            plot(app.ax_th_ellipses, epi_offset(1,:), ...
                epi_offset(2,:), 'c-');

            % Find the line normals
            endo_normals = LineNormals2D(endo_offset');
           
            for i = 1 : numel(wall_angles)
                
                % Find the endo point nearest the angle
                app.lv_props.wall.angles(i) = wall_angles(i);
                
                x_endo = app.lv_props.endo_ellipse.X0_in + ...
                            (app.lv_props.endo_ellipse.a * ...
                                cosd(wall_angles(i)));
                y_endo = app.lv_props.endo_ellipse.Y0_in + ...
                            (app.lv_props.endo_ellipse.b * ...
                                sind(wall_angles(i)));
                
                h = hypot((endo_offset(1,:)-x_endo), ...
                        (endo_offset(2,:)-y_endo))';
                [~,vi] = min(h);

                % Find the endo points for each angle
                app.lv_props.wall.endo_x(i) = endo_offset(1, vi);
                app.lv_props.wall.endo_y(i) = endo_offset(2, vi);

                % Calculate the normal
                n_z = linspace(-5,40,1000);
                n_x = endo_offset(1, vi) + n_z * endo_normals(vi, 1);
                n_y = endo_offset(2, vi) + n_z * endo_normals(vi, 2);
                
                % Find the intersect
                [app.lv_props.wall.epi_x(i), ...
                    app.lv_props.wall.epi_y(i)] = intersections( ...
                        epi_offset(1,:), epi_offset(2,:), ...
                        n_x, n_y);
                
                % Calculate the wall thickness
                app.lv_props.wall.thickness(i) = hypot( ...
                    (app.lv_props.wall.endo_x(i) - ...
                        app.lv_props.wall.epi_x(i)), ...
                    (app.lv_props.wall.endo_y(i) - ...
                        app.lv_props.wall.epi_y(i)));                 
            end
            
            % Draw points
%             plot(app.ax_th_ellipses, ...
%                [app.lv_props.wall.endo_x' app.lv_props.wall.epi_x']', ...
%                [app.lv_props.wall.endo_y' app.lv_props.wall.epi_y']', ...
%                'wo-');
            app.tidy_axes(app.ax_th_ellipses, 'Wall thickness');
            
            % Draw Wall thickness by angle
            cla(app.ax_th_thick);
            plot(app.ax_th_thick, app.lv_props.wall.angles, ...
                    app.lv_props.wall.thickness, 'bo-');
            xlabel(app.ax_th_thick, 'Angle');
            xlim(app.ax_th_thick, [wall_angles(1), wall_angles(length(wall_angles))]);
            ylabel(app.ax_th_thick, 'Thickness');
            ylim(app.ax_th_thick, [0 max(app.lv_props.wall.thickness)]);
        end
    
        function results = draw_results(app)
            % Displays results

            % Summary image
            cla(app.ax_result_im);
            hold(app.ax_result_im, 'on');
            im_back = zeros([size(app.dicom_filtered_frame), 3]);
            im_back(:, :, 1) = app.rv_props.im_mask;
            im_back(:, :, 2) = app.lv_props.im_mask;
            imagesc(app.ax_result_im, app.dicom_filtered_frame);
            h = image(app.ax_result_im, im_back);
            set(h, 'AlphaData', 0.2*sum(im_back,3))
            plot(app.ax_result_im, app.lv_props.lv_centroid(1), ...
                app.lv_props.lv_centroid(2), 'yo')
            
            % Plot boundaries
            for i = 1 : 3
                switch i
                    case  1
                        st = app.lv_props.septum_epi;
                        sym = 'go';
                    case 2
                        st = app.lv_props.opp_epi;
                        sym = 'wo';
                    case 3
                        st = app.lv_props.endo;
                        sym = 'co';
                end

                plot(app.ax_result_im, st.x, st.y, sym);
            end
            
            % Plot ellipses
            plot(app.ax_result_im, app.lv_props.epi_ellipse.x, ...
                app.lv_props.epi_ellipse.y, 'y-');
            plot(app.ax_result_im, app.lv_props.endo_ellipse.x, ...
                app.lv_props.endo_ellipse.y, 'c-');
            
            % Draw wall thickness
            plot(app.ax_result_im, ...
               [app.lv_props.wall.endo_x' app.lv_props.wall.epi_x']', ...
               [app.lv_props.wall.endo_y' app.lv_props.wall.epi_y']', ...
               'w+-');

            app.tidy_axes(app.ax_result_im, 'Analysis');
            
            % Calculate limits and clip
            sx = summary_stats(app.lv_props.wall.epi_x)
            xlim(app.ax_result_im, ...
                round([sx.min-0.5*sx.range sx.max+0.5*sx.range]))
            sy = summary_stats(app.lv_props.wall.epi_y)
            ylim(app.ax_result_im, ...
                round([sy.min-0.5*sy.range sy.max+0.5*sy.range]))

            
        end
        
     end
   

    methods (Access = private)

        % Button pushed function: SelectButton
        function DICOM_select(app, event)
            [file_string, path_string] = uigetfile('*.dcm');
            file_string = fullfile(path_string, file_string);
            
            % Update file
            app.DICOMfileEditField.Value = file_string;
            
            % Update Frame Spinner
            %app.FrameSpinner.Limits = [1 size(app.dicom_data,4)];
            app.FrameSpinner.Value = 1;
           
        end

        % Button pushed function: LoadButton
        function DICOM_load(app, event)
            % Load file string
            file_string = app.DICOMfileEditField.Value;
            app.dicom_data = dicomread(file_string);
            % Select frame
            app.dicom_raw_frame = double(app.dicom_data(:,:,1,app.FrameSpinner.Value));
            % Normalize to max intensity
            app.dicom_raw_frame = app.dicom_raw_frame ./ max(app.dicom_raw_frame(:));
            
            % Display
            cla(app.ax_raw_dicom);
            imagesc(app.ax_raw_dicom, app.dicom_raw_frame);
            app.tidy_axes(app.ax_raw_dicom, 'Raw frame');
            
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Main analysis function
            
            % First filter image
            gauss_sd = app.GaussfilterSDEditField.Value;
            if (gauss_sd > 0)
                app.dicom_filtered_frame = imgaussfilt(app.dicom_raw_frame, ...
                    gauss_sd);
            else
                app.dicom_filtered_frame = app.dicom_raw_frame;
            end
            cla(app.ax_filtered_dicom, "reset");
            imagesc(app.ax_filtered_dicom, app.dicom_filtered_frame);
            app.tidy_axes(app.ax_filtered_dicom, ...
                sprintf('Gaussian filtered with SD=%.2f', gauss_sd))
            
            % Mark ventricles
            app.mark_ventricles()
            
            % Deduce ellipses
            app.deduce_ellipses()

            % Calculate thickness
            app.calculate_thickness()

            
            % Draw results
            app.draw_results()
        end

        % Button pushed function: SaveButton
        function save_settings_measurement(app, event)
            wt = app.lv_props.wall.thickness;
            output_file_string = app.SavePathEditField.Value;
            output_file_string
            wt
            %save(app.SavePathEditField.Value,'wt')
            settings_struct = struct;
            settings_struct.wt = wt;
            settings_struct.gauss_filter_sd = app.GaussfilterSDEditField.Value;
            settings_struct.circle_exp = app.CircleexpansionEditField.Value;
            settings_struct.sd_filter = app.StandarddeviationfilterEditField.Value;
            settings_struct.edge_thickening = app.EdgethickeningEditField.Value;
            settings_struct.seed_contrast = app.SeedcontrastEditField.Value;
            settings_struct.contraction_bias = app.ContractionbiasEditField.Value;
            settings_struct.smoothness_factor = app.SmoothnessfactorEditField.Value;
            settings_struct.contour_iterations = app.ContouriterationsEditField.Value;
            settings_struct.peak_prominence = app.PeakprominenceEditField.Value;
            settings_struct.pixel_interp = app.PixelinterpolationEditField.Value;
            settings_struct.sept_start_angle = app.SeptumstartangleEditField.Value;
            settings_struct.sept_stop_angle = app.SeptumstopangleEditField.Value;
            settings_struct.sept_points = app.SeptumpointsEditField.Value;
            settings_struct.sept_thresh = app.SeptumthresholdEditField.Value;
            settings_struct.opp_start_angle = app.OppositestartangleEditField.Value;
            settings_struct.opp_stop_angle = app.OppositestopangleEditField.Value;
            settings_struct.opp_points = app.OppositepointsEditField.Value;
            settings_struct.opp_thresh = app.OppositethresholdEditField.Value;
            settings_struct.endo_start_angle = app.EndocardiumstartangleEditField.Value;
            settings_struct.endo_stop_angle = app.EndocardiumstopangleEditField.Value;
            settings_struct.endo_points = app.EndocardiumpointsEditField.Value;
            settings_struct.endo_thresh = app.EndocardialthresholdEditField.Value;
            settings_struct.no_thick_calc = app.NoofthicknesscalculationsEditField.Value;
            settings_struct.epi_x = app.lv_props.epi_x;
            settings_struct.epi_y = app.lv_props.epi_y;
            save(app.SavePathEditField.Value,'settings_struct')
        end

        % Button pushed function: LoadSettingsButton
        function load_prev_settings(app, event)
            settings_path = app.SettingsEditField.Value;
            settings = load(settings_path);
            app.GaussfilterSDEditField.Value = settings.settings_struct.gauss_filter_sd;
            app.CircleexpansionEditField.Value = settings.settings_struct.circle_exp;
            app.StandarddeviationfilterEditField.Value = settings.settings_struct.sd_filter;
            app.EdgethickeningEditField.Value = settings.settings_struct.edge_thickening;
            app.SeedcontrastEditField.Value = settings.settings_struct.seed_contrast;
            app.ContractionbiasEditField.Value  = settings.settings_struct.contraction_bias;
            app.SmoothnessfactorEditField.Value = settings.settings_struct.smoothness_factor;
            app.ContouriterationsEditField.Value = settings.settings_struct.contour_iterations;
            app.PeakprominenceEditField.Value = settings.settings_struct.peak_prominence;
            app.PixelinterpolationEditField.Value = settings.settings_struct.pixel_interp;
            app.SeptumstartangleEditField.Value = settings.settings_struct.sept_start_angle;
            app.SeptumstopangleEditField.Value = settings.settings_struct.sept_stop_angle;
            app.SeptumpointsEditField.Value = settings.settings_struct.sept_points;
            app.SeptumthresholdEditField.Value = settings.settings_struct.sept_thresh;
            app.OppositestartangleEditField.Value = settings.settings_struct.opp_start_angle;
            app.OppositestopangleEditField.Value = settings.settings_struct.opp_stop_angle;
            app.OppositepointsEditField.Value = settings.settings_struct.opp_points;
            app.OppositethresholdEditField.Value = settings.settings_struct.opp_thresh;
            app.EndocardiumstartangleEditField.Value = settings.settings_struct.endo_start_angle;
            app.EndocardiumstopangleEditField.Value = settings.settings_struct.endo_stop_angle;
            app.EndocardiumpointsEditField.Value = settings.settings_struct.endo_points;
            app.EndocardialthresholdEditField.Value = settings.settings_struct.endo_thresh;
            app.NoofthicknesscalculationsEditField.Value = settings.settings_struct.no_thick_calc;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RawTab = uitab(app.TabGroup);
            app.RawTab.Title = 'Raw';
            app.ax_raw_dicom = uiaxes(app.RawTab);
            title(app.ax_raw_dicom, 'Raw frame')
            xlabel(app.ax_raw_dicom, 'X')
            ylabel(app.ax_raw_dicom, 'Y')
            zlabel(app.ax_raw_dicom, 'Z')
            app.FilteredTab = uitab(app.TabGroup);
            app.FilteredTab.Title = 'Filtered';
            app.ax_filtered_dicom = uiaxes(app.FilteredTab);
            title(app.ax_filtered_dicom, '')
            xlabel(app.ax_filtered_dicom, 'X')
            ylabel(app.ax_filtered_dicom, 'Y')
            zlabel(app.ax_filtered_dicom, 'Z')
            app.MarkventriclesTab = uitab(app.TabGroup);
            app.MarkventriclesTab.Title = 'Mark ventricles';
            app.ax_mv_circle = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_circle, 'Circle')
            xlabel(app.ax_mv_circle, 'X')
            ylabel(app.ax_mv_circle, 'Y')
            zlabel(app.ax_mv_circle, 'Z')
            app.ax_mv_mask = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_mask, 'Mask')
            xlabel(app.ax_mv_mask, 'X')
            ylabel(app.ax_mv_mask, 'Y')
            zlabel(app.ax_mv_mask, 'Z')
            app.ax_mv_std = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_std, 'Standard deviation')
            xlabel(app.ax_mv_std, 'X')
            ylabel(app.ax_mv_std, 'Y')
            zlabel(app.ax_mv_std, 'Z')
            app.ax_mv_bin = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_bin, 'Binarized')
            xlabel(app.ax_mv_bin, 'X')
            ylabel(app.ax_mv_bin, 'Y')
            zlabel(app.ax_mv_bin, 'Z')
            app.ax_mv_bin_thick = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_bin_thick, 'Thickened')
            xlabel(app.ax_mv_bin_thick, 'X')
            ylabel(app.ax_mv_bin_thick, 'Y')
            zlabel(app.ax_mv_bin_thick, 'Z')
            app.ax_mv_filled = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_filled, 'Filled')
            xlabel(app.ax_mv_filled, 'X')
            ylabel(app.ax_mv_filled, 'Y')
            zlabel(app.ax_mv_filled, 'Z')
            app.ax_mv_contouring = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_contouring, {'Contouring'; ''})
            xlabel(app.ax_mv_contouring, 'X')
            ylabel(app.ax_mv_contouring, 'Y')
            zlabel(app.ax_mv_contouring, 'Z')
            app.ax_mv_L = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_L, 'Labels')
            xlabel(app.ax_mv_L, 'X')
            ylabel(app.ax_mv_L, 'Y')
            zlabel(app.ax_mv_L, 'Z')
            app.ax_mv_thick_masked = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_thick_masked, 'Masked')
            xlabel(app.ax_mv_thick_masked, 'X')
            ylabel(app.ax_mv_thick_masked, 'Y')
            zlabel(app.ax_mv_thick_masked, 'Z')
            app.ax_mv_ventricles = uiaxes(app.MarkventriclesTab);
            title(app.ax_mv_ventricles, {'Ventricles'; ''})
            xlabel(app.ax_mv_ventricles, 'X')
            ylabel(app.ax_mv_ventricles, 'Y')
            zlabel(app.ax_mv_ventricles, 'Z')
            app.ProfilingTab = uitab(app.TabGroup);
            app.ProfilingTab.Title = 'Profiling';
            app.ax_pr_sep_epi_im = uiaxes(app.ProfilingTab);
            title(app.ax_pr_sep_epi_im, 'Septum')
            xlabel(app.ax_pr_sep_epi_im, 'X')
            ylabel(app.ax_pr_sep_epi_im, 'Y')
            zlabel(app.ax_pr_sep_epi_im, 'Z')
            app.ax_pr_opp_epi_im = uiaxes(app.ProfilingTab);
            title(app.ax_pr_opp_epi_im, 'Opposite')
            xlabel(app.ax_pr_opp_epi_im, 'X')
            ylabel(app.ax_pr_opp_epi_im, 'Y')
            zlabel(app.ax_pr_opp_epi_im, 'Z')
            app.ax_pr_endo_im = uiaxes(app.ProfilingTab);
            title(app.ax_pr_endo_im, 'Endocardium')
            xlabel(app.ax_pr_endo_im, 'X')
            ylabel(app.ax_pr_endo_im, 'Y')
            zlabel(app.ax_pr_endo_im, 'Z')
            app.ax_pr_sep_epi_profile = uiaxes(app.ProfilingTab);
            title(app.ax_pr_sep_epi_profile, 'Septum')
            xlabel(app.ax_pr_sep_epi_profile, 'X')
            ylabel(app.ax_pr_sep_epi_profile, 'Y')
            zlabel(app.ax_pr_sep_epi_profile, 'Z')
            app.ax_pr_opp_epi_profile = uiaxes(app.ProfilingTab);
            title(app.ax_pr_opp_epi_profile, 'Septum')
            xlabel(app.ax_pr_opp_epi_profile, 'X')
            ylabel(app.ax_pr_opp_epi_profile, 'Y')
            zlabel(app.ax_pr_opp_epi_profile, 'Z')
            app.ax_pr_endo_profile = uiaxes(app.ProfilingTab);
            title(app.ax_pr_endo_profile, 'Septum')
            xlabel(app.ax_pr_endo_profile, 'X')
            ylabel(app.ax_pr_endo_profile, 'Y')
            zlabel(app.ax_pr_endo_profile, 'Z')
            app.ThicknessTab = uitab(app.TabGroup);
            app.ThicknessTab.Title = 'Thickness ';
            app.ax_th_ellipses = uiaxes(app.ThicknessTab);
            title(app.ax_th_ellipses, 'Title')
            xlabel(app.ax_th_ellipses, 'X')
            ylabel(app.ax_th_ellipses, 'Y')
            zlabel(app.ax_th_ellipses, 'Z')
            app.ax_th_thick = uiaxes(app.ThicknessTab);
            title(app.ax_th_thick, 'Title')
            xlabel(app.ax_th_thick, 'X')
            ylabel(app.ax_th_thick, 'Y')
            zlabel(app.ax_th_thick, 'Z')
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.ax_result_im = uiaxes(app.ResultTab);
            title(app.ax_result_im, 'Title')
            xlabel(app.ax_result_im, 'X')
            ylabel(app.ax_result_im, 'Y')
            zlabel(app.ax_result_im, 'Z')
            app.DICOMPanel = uipanel(app.UIFigure);
            app.DICOMPanel.Title = 'DICOM';
            app.DICOMfileEditFieldLabel = uilabel(app.DICOMPanel);
            app.DICOMfileEditFieldLabel.Text = 'DICOM file';
            app.DICOMfileEditField = uieditfield(app.DICOMPanel, 'text');
            app.DICOMfileEditField.Value = 'C:\ken\GitHub\CampbellMuscleLab\MATLAB\CML_MRI_analysis\data\enIm2.dcm';
            app.SelectButton = uibutton(app.DICOMPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @DICOM_select, true);
            app.SelectButton.Text = 'Select';
            app.FrameSpinnerLabel = uilabel(app.DICOMPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.DICOMPanel);
            app.FrameSpinner.Value = 8;
            app.LoadButton = uibutton(app.DICOMPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @DICOM_load, true);
            app.LoadButton.Text = 'Load';
            app.SettingsEditFieldLabel = uilabel(app.DICOMPanel);
            app.SettingsEditFieldLabel.Text = 'Settings';
            app.SettingsEditField = uieditfield(app.DICOMPanel, 'text');
            app.SettingsEditField.Value = '/path_to_settings/';
            app.LoadSettingsButton = uibutton(app.DICOMPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @load_prev_settings, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.VentricledetectionTab = uitab(app.TabGroup2);
            app.VentricledetectionTab.Title = 'Ventricle detection';
            app.GaussfilterSDEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.GaussfilterSDEditFieldLabel.Text = 'Gauss filter SD';
            app.GaussfilterSDEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.GaussfilterSDEditField.Limits = [0 10];
            app.GaussfilterSDEditField.Value = 1;
            app.CircleexpansionEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.CircleexpansionEditFieldLabel.Text = 'Circle expansion';
            app.CircleexpansionEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.CircleexpansionEditField.Limits = [0 Inf];
            app.CircleexpansionEditField.Value = 3;
            app.ContractionbiasEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.ContractionbiasEditFieldLabel.Text = 'Contraction bias';
            app.ContractionbiasEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.ContractionbiasEditField.Limits = [-1 1];
            app.ContractionbiasEditField.Value = -0.5;
            app.SmoothnessfactorEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.SmoothnessfactorEditFieldLabel.Text = 'Smoothness factor';
            app.SmoothnessfactorEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.SmoothnessfactorEditField.Limits = [0 1];
            app.SmoothnessfactorEditField.Value = 0.5;
            app.ContouriterationsEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.ContouriterationsEditFieldLabel.Text = 'Contour iterations';
            app.ContouriterationsEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.ContouriterationsEditField.Limits = [0 1000];
            app.ContouriterationsEditField.RoundFractionalValues = 'on';
            app.ContouriterationsEditField.Value = 50;
            app.StandarddeviationfilterEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.StandarddeviationfilterEditFieldLabel.Text = 'Standard deviation filter';
            app.StandarddeviationfilterEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.StandarddeviationfilterEditField.Limits = [0 Inf];
            app.StandarddeviationfilterEditField.RoundFractionalValues = 'on';
            app.StandarddeviationfilterEditField.Value = 3;
            app.EdgethickeningEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.EdgethickeningEditFieldLabel.Text = 'Edge thickening';
            app.EdgethickeningEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.EdgethickeningEditField.Limits = [0 10];
            app.EdgethickeningEditField.RoundFractionalValues = 'on';
            app.EdgethickeningEditField.Value = 1;
            app.SeedcontrastEditFieldLabel = uilabel(app.VentricledetectionTab);
            app.SeedcontrastEditFieldLabel.Text = 'Seed contrast';
            app.SeedcontrastEditField = uieditfield(app.VentricledetectionTab, 'numeric');
            app.SeedcontrastEditField.Limits = [0 1];
            app.SeedcontrastEditField.Value = 0.5;
            app.ProfilingTab_2 = uitab(app.TabGroup2);
            app.ProfilingTab_2.Title = 'Profiling';
            app.SeptumstartangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.SeptumstartangleEditFieldLabel.Text = 'Septum start angle';
            app.SeptumstartangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.SeptumstartangleEditField.Limits = [-180 180];
            app.SeptumstartangleEditField.Value = -60;
            app.SeptumstopangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.SeptumstopangleEditFieldLabel.Text = 'Septum stop angle';
            app.SeptumstopangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.SeptumstopangleEditField.Limits = [-180 180];
            app.SeptumstopangleEditField.Value = 30;
            app.SeptumpointsEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.SeptumpointsEditFieldLabel.Text = 'Septum points';
            app.SeptumpointsEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.SeptumpointsEditField.Limits = [0 100];
            app.SeptumpointsEditField.RoundFractionalValues = 'on';
            app.SeptumpointsEditField.Value = 20;
            app.PeakprominenceEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.PeakprominenceEditFieldLabel.Text = 'Peak prominence';
            app.PeakprominenceEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.PeakprominenceEditField.Limits = [0 1];
            app.PeakprominenceEditField.Value = 0.1;
            app.PixelinterpolationEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.PixelinterpolationEditFieldLabel.Text = 'Pixel interpolation';
            app.PixelinterpolationEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.PixelinterpolationEditField.Limits = [0 1];
            app.PixelinterpolationEditField.Value = 0.1;
            app.OppositestartangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.OppositestartangleEditFieldLabel.Text = 'Opposite start angle';
            app.OppositestartangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.OppositestartangleEditField.Limits = [-180 180];
            app.OppositestartangleEditField.Value = 140;
            app.OppositestopangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.OppositestopangleEditFieldLabel.Text = 'Opposite stop angle';
            app.OppositestopangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.OppositestopangleEditField.Limits = [-180 180];
            app.OppositestopangleEditField.Value = -170;
            app.OppositepointsEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.OppositepointsEditFieldLabel.Text = 'Opposite points';
            app.OppositepointsEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.OppositepointsEditField.Limits = [0 100];
            app.OppositepointsEditField.RoundFractionalValues = 'on';
            app.OppositepointsEditField.Value = 20;
            app.EndocardiumstartangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.EndocardiumstartangleEditFieldLabel.Text = 'Endocardium start angle';
            app.EndocardiumstartangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.EndocardiumstartangleEditField.Limits = [-180 180];
            app.EndocardiumstartangleEditField.Value = -100;
            app.EndocardiumstopangleEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.EndocardiumstopangleEditFieldLabel.Text = 'Endocardium stop angle';
            app.EndocardiumstopangleEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.EndocardiumstopangleEditField.Limits = [-180 180];
            app.EndocardiumstopangleEditField.Value = 70;
            app.EndocardiumpointsEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.EndocardiumpointsEditFieldLabel.Text = 'Endocardium points';
            app.EndocardiumpointsEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.EndocardiumpointsEditField.Limits = [0 100];
            app.EndocardiumpointsEditField.RoundFractionalValues = 'on';
            app.EndocardiumpointsEditField.Value = 20;
            app.OppositethresholdEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.OppositethresholdEditFieldLabel.Text = 'Opposite threshold';
            app.OppositethresholdEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.OppositethresholdEditField.Limits = [0 1];
            app.OppositethresholdEditField.Value = 0.5;
            app.EndocardialthresholdEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.EndocardialthresholdEditFieldLabel.Text = 'Endocardial threshold';
            app.EndocardialthresholdEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.EndocardialthresholdEditField.Limits = [0 1];
            app.EndocardialthresholdEditField.Value = 0.7;
            app.SeptumthresholdEditFieldLabel = uilabel(app.ProfilingTab_2);
            app.SeptumthresholdEditFieldLabel.Text = 'Septum threshold';
            app.SeptumthresholdEditField = uieditfield(app.ProfilingTab_2, 'numeric');
            app.ThicknessTab_2 = uitab(app.TabGroup2);
            app.ThicknessTab_2.Title = 'Thickness';
            app.NoofthicknesscalculationsEditFieldLabel = uilabel(app.ThicknessTab_2);
            app.NoofthicknesscalculationsEditFieldLabel.Text = 'No of thickness calculations';
            app.NoofthicknesscalculationsEditField = uieditfield(app.ThicknessTab_2, 'numeric');
            app.NoofthicknesscalculationsEditField.Limits = [0 Inf];
            app.NoofthicknesscalculationsEditField.RoundFractionalValues = 'on';
            app.NoofthicknesscalculationsEditField.Value = 100;
            app.SaveTab = uitab(app.TabGroup2);
            app.SaveTab.Title = 'Save';
            app.SavePathEditFieldLabel = uilabel(app.SaveTab);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.SaveTab, 'text');
            app.SavePathEditField.Value = '/path_to_save_settings/';
            app.SaveButton = uibutton(app.SaveTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save_settings_measurement, true);
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mri_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end